classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimuladorUIFigure               matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConfiguracinEmisorTab           matlab.ui.container.Tab
        SetProbs                        matlab.ui.control.Button
        ProbabilidadaprioriIEditFieldLabel  matlab.ui.control.Label
        value_prob_i                    matlab.ui.control.NumericEditField
        ProbabilidadaprioriIIEditFieldLabel  matlab.ui.control.Label
        value_prob_ii                   matlab.ui.control.NumericEditField
        CantidaddemensajesEditFieldLabel  matlab.ui.control.Label
        amsgs                           matlab.ui.control.NumericEditField
        hist                            matlab.ui.control.UIAxes
        PrimersmboloEditFieldLabel      matlab.ui.control.Label
        first_sym                       matlab.ui.control.EditField
        SegundosmboloEditFieldLabel     matlab.ui.control.Label
        second_sym                      matlab.ui.control.EditField
        ProbabilidaddecadamensajeLabel  matlab.ui.control.Label
        ConstelacinLabel                matlab.ui.control.Label
        CantidaddemensajesaenviarLabel  matlab.ui.control.Label
        PotenciadesealLabel             matlab.ui.control.Label
        PEditFieldLabel                 matlab.ui.control.Label
        power_noise                     matlab.ui.control.NumericEditField
        ModulacinListBoxLabel           matlab.ui.control.Label
        ModulacinListBox                matlab.ui.control.ListBox
        TcnicademodulacinLabel          matlab.ui.control.Label
        ruidoEditFieldLabel             matlab.ui.control.Label
        noise_sig                       matlab.ui.control.NumericEditField
        CaractersticasdelcanalLabel     matlab.ui.control.Label
        ProbabilidadH0Tab               matlab.ui.container.Tab
        poisson_h0                      matlab.ui.control.CheckBox
        EditField_2Label                matlab.ui.control.Label
        lambda_h0                       matlab.ui.control.NumericEditField
        normal_h0                       matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        mu_h0                           matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        sigma_h0                        matlab.ui.control.NumericEditField
        FutureCheckBox                  matlab.ui.control.CheckBox
        plotProb_h0                     matlab.ui.control.UIAxes
        GraficarButton                  matlab.ui.control.Button
        ProbabilidadH1Tab               matlab.ui.container.Tab
        poisson_h1                      matlab.ui.control.CheckBox
        EditField_2Label_2              matlab.ui.control.Label
        lambda_h1                       matlab.ui.control.NumericEditField
        normal_h1                       matlab.ui.control.CheckBox
        EditField_4Label                matlab.ui.control.Label
        mu_h1                           matlab.ui.control.NumericEditField
        EditField_5Label                matlab.ui.control.Label
        sigma_h1                        matlab.ui.control.NumericEditField
        FutureCheckBox_2                matlab.ui.control.CheckBox
        plotProb_h1                     matlab.ui.control.UIAxes
        GraficarButton_2                matlab.ui.control.Button
        ConfiguracinReceptorTab         matlab.ui.container.Tab
        DeteccinSwitchLabel             matlab.ui.control.Label
        DeteccinSwitch                  matlab.ui.control.Switch
        both_prob                       matlab.ui.control.UIAxes
        SimularButton                   matlab.ui.control.Button
        label0                          matlab.ui.control.Label
        threshold                       matlab.ui.control.Label
        ResultadosTab                   matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        TextAreaLabel                   matlab.ui.control.Label
        text                            matlab.ui.control.TextArea
    end


    properties (Access = public)
        probability_1 % Description
        probability_0 % Description
        x = 0:100;
        messages;
        codification;
        modulation;
        channel;
        decodification;
        threshold_property;
        
    end

    methods (Access = private)
    
        function verify = verify_probs(~,value_i,value_ii)
            if((value_i + value_ii) > 1)
                verify = false;
                return
            end
            
            verify = true;
            
        end
        
        function plot_poisson(app,n,plotter)
            y = poisspdf(app.x,n);
            plot(plotter,app.x,y);
        end
        
        function plot_two_prob(app)
            y = poisspdf(app.x,app.lambda_h0.Value)*app.value_prob_i.Value;
            plot(app.both_prob,app.x,y);
            hold(app.both_prob,'on')
            y = poisspdf(app.x,app.lambda_h1.Value)*app.value_prob_ii.Value;
            plot(app.both_prob,app.x,y);           
                   
            
        end
        
        function plot_normal(app,mu,sigma)
            
        end
        
        function generate_transmision(app)
            app.messages = zeros(1,app.amsgs.Value);
            for i=1:length(app.messages)
                app.messages(1,i) = rand;
            end
        end
        
        
        function generate_codification(app)
            app.codification = zeros(1,app.amsgs.Value);
            for i=1:app.amsgs.Value
                app.codification(1,i) = generate_codes(app,app.messages(1,i));
            end
        end


        function generate_mapping(app)
            app.modulation = zeros(1,app.amsgs.Value);
            for i=1:app.amsgs.Value
                if(app.codification(1,i) == 0)
                    app.modulation(1,i) = string(app.first_sym.Value);
                else
                    app.modulation(1,i) = string(app.second_sym.Value);
                end
            end
        end
        
        function generate_channel(app)
            app.channel = zeros(1,app.amsgs.Value);
            %number_of_values = linspace(-10,10,app.amsgs.Value);
            for i=1:length(app.channel)
                N = normrnd(0,app.noise_sig.Value);
                Z = sqrt(app.power_noise.Value) * N;
                app.channel(1,i) = double(app.modulation(1,i)) + Z;
            end
        end
        
        function eta = get_eta(app)
            eta = app.value_prob_i.Value./app.value_prob_ii.Value;
        end
        
        function generate_decoding(app)
            app.decodification = zeros(1,app.amsgs.Value);
            tthreshold = get_threshold_poisson(app,app.lambda_h0.Value,app.lambda_h1.Value);
            for i=1:length(app.decodification)
                if(app.channel(1,i) >= tthreshold)
                    app.decodification(1,i) = 1;
                else
                    app.decodification(1,i) = -1;
                end
            end
        end
        
        function threshold_r = get_threshold_poisson(app,lambda0,lambda1)
            threshold_r = -log(get_eta(app)*exp(-lambda0)*exp(lambda1))/(log(lambda0) - log(lambda1));
            app.threshold_property = threshold_r;
        end
        
        function code = generate_codes(app,n)
             if(n <= app.value_prob_i.Value)
                 code = 0;
             else
                 code = 1;
             end
        end
        
    end


    methods (Access = private)

        % Button pushed function: SetProbs
        function SetProbsButtonPushed(app, event)
            if(~verify_probs(app,app.value_prob_i.Value,app.value_prob_ii.Value))
                return
            end
            %histogram(app.hist,double(app.codification(1,:)));
            
        %uialert(app.SimuladorUIFigure,'La suma no puede ser mayor a 1','Error');
        %app.value_prob_i.Value = 0;
        %app.value_prob_ii.Value = 0;
        end

        % Value changed function: poisson_h0
        function poisson_h0ValueChanged(app, event)
            value = app.poisson_h0.Value;
            if(value)
                app.lambda_h0.Editable = 'on';
                return
            end
            app.lambda_h0.Editable = 'off';
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            plot_poisson(app,app.lambda_h0.Value,app.plotProb_h0);
        end

        % Value changed function: ModulacinListBox
        function ModulacinListBoxValueChanged(app, event)
            value = app.ModulacinListBox.Value;
            switch(value)
                case 'Antipodal (1,-1)'
                    disp('Op_1')
                case 'PCM'
                    disp('Op_2')
                case 'PAM'
                    disp('Op_3')
                case 'ASK - OOK'
                    disp('Op_4')
                case 'SS'
                    disp('Op_5')
                case 'PSK - BPSK'
                    disp('Op_6')
            end
        end

        % Value changed function: DeteccinSwitch
        function DeteccinSwitchValueChanged(app, event)
            app.prb_0.Editable = 'on';
            app.prb_1.Editable = 'on';
            app.prb_0.Value = 0;
            app.prb_1.Value = 0;
            switch app.DeteccinSwitch.Value
                case 'MAP'
                    app.probability_0 = app.prb_0.Value;
                    app.probability_1 = app.prb_1.Value;
                    
                case 'ML'
                    app.prb_0.Value = 0.5;
                    app.prb_1.Value = 0.5;
                    app.prb_0.Editable = 'off';
                    app.prb_1.Editable = 'off';
            end
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)

            app.both_prob.cla;
            plot_two_prob(app)
            generate_transmision(app)
            generate_codification(app)
            generate_mapping(app)
            generate_channel(app)
            generate_decoding(app)
            app.threshold.Text = sprintf('%.2f',app.threshold_property);
            disp(app.messages')
            disp(app.codification')
            disp(app.modulation')
            disp(app.channel')
            disp(app.decodification)
        end

        % Value changed function: poisson_h1
        function poisson_h1ValueChanged(app, event)
            value = app.poisson_h1.Value;
            if(value)
                app.lambda_h1.Editable = 'on';
                return
            end
            app.lambda_h1.Editable = 'off';
            
        end

        % Button pushed function: GraficarButton_2
        function GraficarButton_2Pushed(app, event)
             plot_poisson(app,app.lambda_h1.Value,app.plotProb_h1);
        end

        % Value changed function: value_prob_i
        function value_prob_iValueChanged(app, event)
            value = app.value_prob_i.Value;
            app.value_prob_ii.Value = 1 - value;
        end

        % Value changed function: value_prob_ii
        function value_prob_iiValueChanged(app, event)
            value = app.value_prob_ii.Value;
            app.value_prob_i.Value = 1 - value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimuladorUIFigure = uifigure;
            app.SimuladorUIFigure.Name = 'Simulador';
            app.TabGroup = uitabgroup(app.SimuladorUIFigure);
            app.ConfiguracinEmisorTab = uitab(app.TabGroup);
            app.ConfiguracinEmisorTab.Title = 'Configuración: Emisor';
            app.SetProbs = uibutton(app.ConfiguracinEmisorTab, 'push');
            app.SetProbs.ButtonPushedFcn = createCallbackFcn(app, @SetProbsButtonPushed, true);
            app.SetProbs.Text = 'Configurar';
            app.ProbabilidadaprioriIEditFieldLabel = uilabel(app.ConfiguracinEmisorTab);
            app.ProbabilidadaprioriIEditFieldLabel.Text = 'Probabilidad a priori I';
            app.value_prob_i = uieditfield(app.ConfiguracinEmisorTab, 'numeric');
            app.value_prob_i.Limits = [0 1];
            app.value_prob_i.ValueDisplayFormat = '%.2f';
            app.value_prob_i.ValueChangedFcn = createCallbackFcn(app, @value_prob_iValueChanged, true);
            app.ProbabilidadaprioriIIEditFieldLabel = uilabel(app.ConfiguracinEmisorTab);
            app.ProbabilidadaprioriIIEditFieldLabel.Text = 'Probabilidad a priori II';
            app.value_prob_ii = uieditfield(app.ConfiguracinEmisorTab, 'numeric');
            app.value_prob_ii.Limits = [0 1];
            app.value_prob_ii.ValueDisplayFormat = '%.2f';
            app.value_prob_ii.ValueChangedFcn = createCallbackFcn(app, @value_prob_iiValueChanged, true);
            app.CantidaddemensajesEditFieldLabel = uilabel(app.ConfiguracinEmisorTab);
            app.CantidaddemensajesEditFieldLabel.Text = 'Cantidad de mensajes';
            app.amsgs = uieditfield(app.ConfiguracinEmisorTab, 'numeric');
            app.amsgs.Limits = [1 Inf];
            app.amsgs.Value = 1;
            app.hist = uiaxes(app.ConfiguracinEmisorTab);
            title(app.hist, 'Histograma')
            xlabel(app.hist, 'X')
            ylabel(app.hist, 'Y')
            app.PrimersmboloEditFieldLabel = uilabel(app.ConfiguracinEmisorTab);
            app.PrimersmboloEditFieldLabel.Text = 'Primer símbolo';
            app.first_sym = uieditfield(app.ConfiguracinEmisorTab, 'text');
            app.SegundosmboloEditFieldLabel = uilabel(app.ConfiguracinEmisorTab);
            app.SegundosmboloEditFieldLabel.Text = 'Segundo símbolo';
            app.second_sym = uieditfield(app.ConfiguracinEmisorTab, 'text');
            app.ProbabilidaddecadamensajeLabel = uilabel(app.ConfiguracinEmisorTab);
            app.ProbabilidaddecadamensajeLabel.Text = 'Probabilidad de cada mensaje';
            app.ConstelacinLabel = uilabel(app.ConfiguracinEmisorTab);
            app.ConstelacinLabel.Text = 'Constelación';
            app.CantidaddemensajesaenviarLabel = uilabel(app.ConfiguracinEmisorTab);
            app.CantidaddemensajesaenviarLabel.Text = 'Cantidad de mensajes a enviar';
            app.PotenciadesealLabel = uilabel(app.ConfiguracinEmisorTab);
            app.PotenciadesealLabel.Text = 'Potencia de señal';
            app.PEditFieldLabel = uilabel(app.ConfiguracinEmisorTab);
            app.PEditFieldLabel.Text = 'P';
            app.power_noise = uieditfield(app.ConfiguracinEmisorTab, 'numeric');
            app.power_noise.Value = 1;
            app.ModulacinListBoxLabel = uilabel(app.ConfiguracinEmisorTab);
            app.ModulacinListBoxLabel.Text = 'Modulación';
            app.ModulacinListBox = uilistbox(app.ConfiguracinEmisorTab);
            app.ModulacinListBox.Items = {'Antipodal (1,-1)', 'PCM', 'PAM', 'ASK - OOK', 'SS', 'PSK - BPSK'};
            app.ModulacinListBox.ValueChangedFcn = createCallbackFcn(app, @ModulacinListBoxValueChanged, true);
            app.ModulacinListBox.Value = 'Antipodal (1,-1)';
            app.TcnicademodulacinLabel = uilabel(app.ConfiguracinEmisorTab);
            app.TcnicademodulacinLabel.Text = 'Técnica de modulación';
            app.ruidoEditFieldLabel = uilabel(app.ConfiguracinEmisorTab);
            app.ruidoEditFieldLabel.Text = 'σ ruido';
            app.noise_sig = uieditfield(app.ConfiguracinEmisorTab, 'numeric');
            app.noise_sig.Limits = [1 Inf];
            app.noise_sig.Value = 1;
            app.CaractersticasdelcanalLabel = uilabel(app.ConfiguracinEmisorTab);
            app.CaractersticasdelcanalLabel.Text = 'Características del canal';
            app.ProbabilidadH0Tab = uitab(app.TabGroup);
            app.ProbabilidadH0Tab.Title = 'Probabilidad H(0)';
            app.poisson_h0 = uicheckbox(app.ProbabilidadH0Tab);
            app.poisson_h0.ValueChangedFcn = createCallbackFcn(app, @poisson_h0ValueChanged, true);
            app.poisson_h0.Text = 'Poisson';
            app.EditField_2Label = uilabel(app.ProbabilidadH0Tab);
            app.EditField_2Label.Text = 'λ';
            app.lambda_h0 = uieditfield(app.ProbabilidadH0Tab, 'numeric');
            app.lambda_h0.ValueDisplayFormat = '%.2f';
            app.lambda_h0.Editable = 'off';
            app.normal_h0 = uicheckbox(app.ProbabilidadH0Tab);
            app.normal_h0.Text = 'Normal';
            app.Label = uilabel(app.ProbabilidadH0Tab);
            app.Label.Text = 'μ';
            app.mu_h0 = uieditfield(app.ProbabilidadH0Tab, 'numeric');
            app.mu_h0.ValueDisplayFormat = '%.2f';
            app.mu_h0.Editable = 'off';
            app.EditField_3Label = uilabel(app.ProbabilidadH0Tab);
            app.EditField_3Label.Text = 'σ';
            app.sigma_h0 = uieditfield(app.ProbabilidadH0Tab, 'numeric');
            app.sigma_h0.ValueDisplayFormat = '%.2f';
            app.sigma_h0.Editable = 'off';
            app.FutureCheckBox = uicheckbox(app.ProbabilidadH0Tab);
            app.FutureCheckBox.Text = 'Future';
            app.plotProb_h0 = uiaxes(app.ProbabilidadH0Tab);
            title(app.plotProb_h0, 'Función de distribución de probabilidad')
            xlabel(app.plotProb_h0, 'X')
            ylabel(app.plotProb_h0, 'P(X)')
            app.GraficarButton = uibutton(app.ProbabilidadH0Tab, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.ProbabilidadH1Tab = uitab(app.TabGroup);
            app.ProbabilidadH1Tab.Title = 'Probabilidad H(1)';
            app.poisson_h1 = uicheckbox(app.ProbabilidadH1Tab);
            app.poisson_h1.ValueChangedFcn = createCallbackFcn(app, @poisson_h1ValueChanged, true);
            app.poisson_h1.Text = 'Poisson';
            app.EditField_2Label_2 = uilabel(app.ProbabilidadH1Tab);
            app.EditField_2Label_2.Text = 'λ';
            app.lambda_h1 = uieditfield(app.ProbabilidadH1Tab, 'numeric');
            app.lambda_h1.ValueDisplayFormat = '%.2f';
            app.lambda_h1.Editable = 'off';
            app.normal_h1 = uicheckbox(app.ProbabilidadH1Tab);
            app.normal_h1.Text = 'Normal';
            app.EditField_4Label = uilabel(app.ProbabilidadH1Tab);
            app.EditField_4Label.Text = 'μ';
            app.mu_h1 = uieditfield(app.ProbabilidadH1Tab, 'numeric');
            app.mu_h1.ValueDisplayFormat = '%.2f';
            app.mu_h1.Editable = 'off';
            app.EditField_5Label = uilabel(app.ProbabilidadH1Tab);
            app.EditField_5Label.Text = 'σ';
            app.sigma_h1 = uieditfield(app.ProbabilidadH1Tab, 'numeric');
            app.sigma_h1.ValueDisplayFormat = '%.2f';
            app.sigma_h1.Editable = 'off';
            app.FutureCheckBox_2 = uicheckbox(app.ProbabilidadH1Tab);
            app.FutureCheckBox_2.Text = 'Future';
            app.plotProb_h1 = uiaxes(app.ProbabilidadH1Tab);
            title(app.plotProb_h1, 'Función de distribución de probabilidad')
            xlabel(app.plotProb_h1, 'X')
            ylabel(app.plotProb_h1, 'P(X)')
            app.GraficarButton_2 = uibutton(app.ProbabilidadH1Tab, 'push');
            app.GraficarButton_2.ButtonPushedFcn = createCallbackFcn(app, @GraficarButton_2Pushed, true);
            app.GraficarButton_2.Text = 'Graficar';
            app.ConfiguracinReceptorTab = uitab(app.TabGroup);
            app.ConfiguracinReceptorTab.Title = 'Configuración: Receptor';
            app.DeteccinSwitchLabel = uilabel(app.ConfiguracinReceptorTab);
            app.DeteccinSwitchLabel.Text = 'Detección';
            app.DeteccinSwitch = uiswitch(app.ConfiguracinReceptorTab, 'slider');
            app.DeteccinSwitch.Items = {'MAP', 'ML'};
            app.DeteccinSwitch.ValueChangedFcn = createCallbackFcn(app, @DeteccinSwitchValueChanged, true);
            app.DeteccinSwitch.Value = 'MAP';
            app.both_prob = uiaxes(app.ConfiguracinReceptorTab);
            title(app.both_prob, 'Gráfica de hipótesis')
            xlabel(app.both_prob, 'X')
            ylabel(app.both_prob, 'Y')
            app.SimularButton = uibutton(app.ConfiguracinReceptorTab, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.label0 = uilabel(app.ConfiguracinReceptorTab);
            app.label0.Text = 'Umbral (Λ) :';
            app.threshold = uilabel(app.ConfiguracinReceptorTab);
            app.threshold.Text = '0';
            app.ResultadosTab = uitab(app.TabGroup);
            app.ResultadosTab.Title = 'Resultados';
            app.UIAxes4 = uiaxes(app.ResultadosTab);
            title(app.UIAxes4, 'SNR/BER')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.TextAreaLabel = uilabel(app.ResultadosTab);
            app.TextAreaLabel.Text = '';
            app.text = uitextarea(app.ResultadosTab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimuladorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimuladorUIFigure)
        end
    end
end